classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ParametersPanel                matlab.ui.container.Panel
        RecordAudioParametersLabel     matlab.ui.control.Label
        ReverbParametersLabel          matlab.ui.control.Label
        DeviceIDEditFieldLabel         matlab.ui.control.Label
        DeviceIDEditField              matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyEditField     matlab.ui.control.NumericEditField
        NoofBitsEditFieldLabel         matlab.ui.control.Label
        NoofBitsEditField              matlab.ui.control.NumericEditField
        PredelayEditFieldLabel         matlab.ui.control.Label
        PredelayEditField              matlab.ui.control.NumericEditField
        LowpassFilterCutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        LowpassFilterCutoffFrequencyEditField  matlab.ui.control.NumericEditField
        DensityofReverbTailEditFieldLabel  matlab.ui.control.Label
        DensityofReverbTailEditField   matlab.ui.control.NumericEditField
        HighFrequencyDampingEditFieldLabel  matlab.ui.control.Label
        HighFrequencyDampingEditField  matlab.ui.control.NumericEditField
        SampleRateEditFieldLabel       matlab.ui.control.Label
        SampleRateEditField            matlab.ui.control.NumericEditField
        Panel2                         matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes3_2                      matlab.ui.control.UIAxes
        AddingReverbtoanAudioSignalLabel  matlab.ui.control.Label
        ProgramStatusLabel             matlab.ui.control.Label
        ProgramStatusTextArea          matlab.ui.control.TextArea
        StartRecordingButton           matlab.ui.control.Button
        PerformOperationButton         matlab.ui.control.Button
        PlayOriginalSignalButton       matlab.ui.control.Button
        PlayReverbAudioButton          matlab.ui.control.Button
        GenerateGraphsButton           matlab.ui.control.Button
        ApplicationByChristopherNiloACaysidoForCPE122LA1Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        time_dom % Time Domain
        record_audio % Record of Audio Object
        record_data % Data of the audio file
        reverb_audio % Playable Reverb Audio
        reverb_data % Data of Reverb Audio
        player_obj_1 % Playable Audio Object 
        player_obj_2 % Playable Reverb Object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
        Device = app.DeviceIDEditField.Value;
        Sampling_Freq = app.SamplingFrequencyEditField.Value;
        Bits = app.NoofBitsEditField.Value;
        app.record_audio = audiorecorder(Sampling_Freq,Bits,1,Device);
        app.ProgramStatusTextArea.Value = 'Start Speaking Now';
        recordblocking(app.record_audio,7);
        app.ProgramStatusTextArea.Value = 'Audio Has Been Recorded';
        app.player_obj_1 = audioplayer(app.record_audio);
        app.record_data = getaudiodata(app.record_audio);
        end

        % Button pushed function: PerformOperationButton
        function PerformOperationButtonPushed(app, event)
        predelay = app.PredelayEditField.Value;
        cutoff = app.LowpassFilterCutoffFrequencyEditField.Value;
        density = app.DensityofReverbTailEditField.Value;
        damping = app.HighFrequencyDampingEditField.Value;
        samplerate = app.SampleRateEditField.Value;
        reverb = reverberator('PreDelay',predelay,'HighCutFrequency',cutoff,'Diffusion',density,'HighFrequencyDamping',damping,'SampleRate',samplerate);
        app.reverb_audio = reverb(app.record_data);
        app.ProgramStatusTextArea.Value = 'Applied Reverb to Original Audio Signal';
        app.ProgramStatusTextArea.Value = 'Created a playable Reverb Audio Object';
        app.ProgramStatusTextArea.Value = 'Created a Reverb Data Object';
        end

        % Button pushed function: PlayOriginalSignalButton
        function PlayOriginalSignalButtonPushed(app, event)
            play(app.player_obj_1);
        end

        % Button pushed function: PlayReverbAudioButton
        function PlayReverbAudioButtonPushed(app, event)
            deviceWriter = audioDeviceWriter('Device',"Speakers (Conexant SmartAudio HD)",'SampleRate',app.SampleRateEditField.Value);
            deviceWriter(app.reverb_audio);
        end

        % Button pushed function: GenerateGraphsButton
        function GenerateGraphsButtonPushed(app, event)
            plot(app.UIAxes,app.record_data);
            plot(app.UIAxes2,app.reverb_data);
            fft_orig = fft(app.record_data);
            fft_reverb = fft(app.reverb_data);
            plot(app.UIAxes3,fft_orig);
            plot(app.UIAxes3_2,fft_reverb);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.RecordAudioParametersLabel = uilabel(app.ParametersPanel);
            app.RecordAudioParametersLabel.Text = 'Record Audio Parameters';
            app.ReverbParametersLabel = uilabel(app.ParametersPanel);
            app.ReverbParametersLabel.Text = 'Reverb Parameters';
            app.DeviceIDEditFieldLabel = uilabel(app.ParametersPanel);
            app.DeviceIDEditFieldLabel.Text = 'Device ID';
            app.DeviceIDEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DeviceIDEditField.ValueDisplayFormat = '%.0f';
            app.DeviceIDEditField.Value = 2;
            app.SamplingFrequencyEditFieldLabel = uilabel(app.ParametersPanel);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SamplingFrequencyEditField.ValueDisplayFormat = '%.0f';
            app.SamplingFrequencyEditField.Value = 11025;
            app.NoofBitsEditFieldLabel = uilabel(app.ParametersPanel);
            app.NoofBitsEditFieldLabel.Text = 'No. of Bits';
            app.NoofBitsEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.NoofBitsEditField.ValueDisplayFormat = '%.0f';
            app.NoofBitsEditField.Value = 8;
            app.PredelayEditFieldLabel = uilabel(app.ParametersPanel);
            app.PredelayEditFieldLabel.Text = 'Pre-delay';
            app.PredelayEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.PredelayEditField.ValueDisplayFormat = '%.1f';
            app.PredelayEditField.Value = 0.5;
            app.LowpassFilterCutoffFrequencyEditFieldLabel = uilabel(app.ParametersPanel);
            app.LowpassFilterCutoffFrequencyEditFieldLabel.Text = 'Lowpass Filter Cutoff Frequency';
            app.LowpassFilterCutoffFrequencyEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.LowpassFilterCutoffFrequencyEditField.ValueDisplayFormat = '%.0f';
            app.LowpassFilterCutoffFrequencyEditField.Value = 20000;
            app.DensityofReverbTailEditFieldLabel = uilabel(app.ParametersPanel);
            app.DensityofReverbTailEditFieldLabel.Text = 'Density of Reverb Tail';
            app.DensityofReverbTailEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DensityofReverbTailEditField.ValueDisplayFormat = '%.2f';
            app.DensityofReverbTailEditField.Value = 0.05;
            app.HighFrequencyDampingEditFieldLabel = uilabel(app.ParametersPanel);
            app.HighFrequencyDampingEditFieldLabel.Text = 'High-Frequency Damping';
            app.HighFrequencyDampingEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.HighFrequencyDampingEditField.ValueDisplayFormat = '%.3f';
            app.HighFrequencyDampingEditField.Value = 0.005;
            app.SampleRateEditFieldLabel = uilabel(app.ParametersPanel);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRateEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SampleRateEditField.ValueDisplayFormat = '%.0f';
            app.SampleRateEditField.Value = 11025;
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.UIAxes = uiaxes(app.Panel2);
            title(app.UIAxes, 'Original Audio Signal')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.Panel2);
            title(app.UIAxes2, 'Audio with Reverb')
            xlabel(app.UIAxes2, 'Samples')
            ylabel(app.UIAxes2, {'Amplitude'; ''})
            app.UIAxes2.XTick = [0 1 2 3 4 5 6 7];
            app.UIAxes2.XTickLabel = {'0'; '1'; '0'; '1'; '0'; '5'; '6'};
            app.UIAxes3 = uiaxes(app.Panel2);
            title(app.UIAxes3, 'Frequency Domain of Audio with Reverb')
            xlabel(app.UIAxes3, 'Samples')
            ylabel(app.UIAxes3, 'Frequency')
            app.UIAxes3_2 = uiaxes(app.Panel2);
            title(app.UIAxes3_2, 'Frequency Domain of Original Audio')
            xlabel(app.UIAxes3_2, 'Samples')
            ylabel(app.UIAxes3_2, 'Amplitude')
            app.AddingReverbtoanAudioSignalLabel = uilabel(app.UIFigure);
            app.AddingReverbtoanAudioSignalLabel.Text = {'Adding Reverb to an '; 'Audio Signal'};
            app.ProgramStatusLabel = uilabel(app.UIFigure);
            app.ProgramStatusLabel.Text = {'Program'; ' Status'};
            app.ProgramStatusTextArea = uitextarea(app.UIFigure);
            app.StartRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.PerformOperationButton = uibutton(app.UIFigure, 'push');
            app.PerformOperationButton.ButtonPushedFcn = createCallbackFcn(app, @PerformOperationButtonPushed, true);
            app.PerformOperationButton.Text = 'Perform Operation';
            app.PlayOriginalSignalButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalSignalButtonPushed, true);
            app.PlayOriginalSignalButton.Text = 'Play Original Signal';
            app.PlayReverbAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayReverbAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayReverbAudioButtonPushed, true);
            app.PlayReverbAudioButton.Text = 'Play Reverb Audio';
            app.GenerateGraphsButton = uibutton(app.UIFigure, 'push');
            app.GenerateGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGraphsButtonPushed, true);
            app.GenerateGraphsButton.Text = 'Generate Graphs';
            app.ApplicationByChristopherNiloACaysidoForCPE122LA1Label = uilabel(app.UIFigure);
            app.ApplicationByChristopherNiloACaysidoForCPE122LA1Label.Text = {'Application By Christopher Nilo A. Caysido'; 'For CPE122L - A1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end